classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BiseccionTab                    matlab.ui.container.Tab
        CriteriodeparadaEditField       matlab.ui.control.NumericEditField
        CriteriodeparadaEditFieldLabel  matlab.ui.control.Label
        ValorfinalXsEditField           matlab.ui.control.NumericEditField
        ValorfinalXsEditFieldLabel      matlab.ui.control.Label
        ValorinicialXiEditField         matlab.ui.control.NumericEditField
        ValorinicialXiEditFieldLabel    matlab.ui.control.Label
        FuncionEditField                matlab.ui.control.EditField
        FuncionEditFieldLabel           matlab.ui.control.Label
        METODODEBISECCIONPanel          matlab.ui.container.Panel
        CalcularButton                  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        GraficaBisecion                 matlab.ui.control.UIAxes
        FalsaposicionTab                matlab.ui.container.Tab
        CriteriodeparadaEditField_2     matlab.ui.control.NumericEditField
        CriteriodeparadaEditField_2Label  matlab.ui.control.Label
        ValorfinalXsEditField_2         matlab.ui.control.NumericEditField
        ValorfinalXsEditField_2Label    matlab.ui.control.Label
        FuncionEditField_2              matlab.ui.control.EditField
        FuncionEditField_2Label         matlab.ui.control.Label
        ValorinicialXiEditField_2       matlab.ui.control.NumericEditField
        ValorinicialXiEditField_2Label  matlab.ui.control.Label
        METODODEFALSAPOSICIONPanel      matlab.ui.container.Panel
        CalcularButton_2                matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        GraficaFalsaPosicion            matlab.ui.control.UIAxes
        PuntofijoTab                    matlab.ui.container.Tab
        CriteriodeparadaEditField_3     matlab.ui.control.NumericEditField
        CriteriodeparadaEditField_3Label  matlab.ui.control.Label
        ValorinicialEditField_3         matlab.ui.control.NumericEditField
        ValorinicialEditFieldLabel      matlab.ui.control.Label
        METODODEPUNTOFIJOPanel          matlab.ui.container.Panel
        CalcularButton_3                matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        FunciongxEditField              matlab.ui.control.EditField
        FunciongxEditFieldLabel         matlab.ui.control.Label
        FuncionfxEditField              matlab.ui.control.EditField
        FuncionfxEditFieldLabel         matlab.ui.control.Label
        GraficaPuntoFijo                matlab.ui.control.UIAxes
        NewRapshonTab                   matlab.ui.container.Tab
        METODODENEWRAPSHONPanel         matlab.ui.container.Panel
        FuncionEditField_4              matlab.ui.control.EditField
        FuncionEditField_3Label         matlab.ui.control.Label
        CriteriodeparadaEditField_4     matlab.ui.control.NumericEditField
        CriteriodeparadaEditField_4Label  matlab.ui.control.Label
        ValorinicialxEditField_4        matlab.ui.control.NumericEditField
        ValorinicialxEditFieldLabel     matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        CalcularButton_4                matlab.ui.control.Button
        GraficaNewRapshon               matlab.ui.control.UIAxes
        DerivadaTab                     matlab.ui.container.Tab
        RESULTADOSPanel                 matlab.ui.container.Panel
        HaciaAdelanteTercera            matlab.ui.control.Label
        HaciaCentradaTercera            matlab.ui.control.Label
        HaciaAtrasTercera               matlab.ui.control.Label
        TerceraderivadaLabel            matlab.ui.control.Label
        HaciaAdelanteSegunda            matlab.ui.control.Label
        HaciaCentradaSegunda            matlab.ui.control.Label
        HaciaAtrasSegunda               matlab.ui.control.Label
        SegundaderivadaLabel            matlab.ui.control.Label
        HaciaAdelantePrimera            matlab.ui.control.Label
        HaciaCentradaPrimera            matlab.ui.control.Label
        HaciaAtrasPrimera               matlab.ui.control.Label
        PrimerderivadaLabel             matlab.ui.control.Label
        METODODEDERIVADASDEALTAPRECISIONPanel  matlab.ui.container.Panel
        CalcularButton_5                matlab.ui.control.Button
        HEditField                      matlab.ui.control.NumericEditField
        HEditFieldLabel                 matlab.ui.control.Label
        XiEditField                     matlab.ui.control.NumericEditField
        XiEditFieldLabel                matlab.ui.control.Label
        FuncionEditField_5              matlab.ui.control.EditField
        FuncionEditField_5Label         matlab.ui.control.Label
        Simpson13Tab                    matlab.ui.container.Tab
        RESULTADOPanel                  matlab.ui.container.Panel
        PorcentajeErrorSimpson1_3       matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        ResultadoSimpson1_3_Real        matlab.ui.control.Label
        ValorrealintegralLabel          matlab.ui.control.Label
        ResultadoSimpson1_3             matlab.ui.control.Label
        ElresultadodelaintegralLabel    matlab.ui.control.Label
        METODODESIMPSON13Panel          matlab.ui.container.Panel
        CalcularButton_6                matlab.ui.control.Button
        LmitesuperiordelaintegralEditField_6  matlab.ui.control.NumericEditField
        LmitesuperiordelaintegralEditFieldLabel  matlab.ui.control.Label
        LmiteinferiordelaintegralEditField_6  matlab.ui.control.NumericEditField
        LmiteinferiordelaintegralLabel  matlab.ui.control.Label
        NumerodesubintervalosEditField_6  matlab.ui.control.NumericEditField
        NmerodesubintervalosLabel       matlab.ui.control.Label
        FuncionEditField_6              matlab.ui.control.EditField
        FuncionEditField_6Label         matlab.ui.control.Label
        GraficaSimpson1_3               matlab.ui.control.UIAxes
        Simpson38Tab                    matlab.ui.container.Tab
        RESULTADOPanel_2                matlab.ui.container.Panel
        PorcentajeErrorSimpson3_8       matlab.ui.control.Label
        ErroresLabel                    matlab.ui.control.Label
        ResultadoSimpson3_8_Real        matlab.ui.control.Label
        ValorrealintegralLabel_2        matlab.ui.control.Label
        ResultadoSimpson3_8             matlab.ui.control.Label
        ElresultadodelaintegralLabel_2  matlab.ui.control.Label
        METODODESIMPSON38Panel          matlab.ui.container.Panel
        CalcularButton_7                matlab.ui.control.Button
        LmitesuperiordelaintegralEditField_7  matlab.ui.control.NumericEditField
        LmitesuperiordelaintegralEditField_7Label  matlab.ui.control.Label
        LmiteinferiordelaintegralEditField_7  matlab.ui.control.NumericEditField
        LmiteinferiordelaintegralEditField_7Label  matlab.ui.control.Label
        NumerodesubintervalosEditField_7  matlab.ui.control.NumericEditField
        NmerodesubintervalosLabel_2     matlab.ui.control.Label
        FuncionEditField_7              matlab.ui.control.EditField
        FuncionEditField_7Label         matlab.ui.control.Label
        GraficaSimpson3_8               matlab.ui.control.UIAxes
        TrapeciomultipleTab             matlab.ui.container.Tab
        RESULTADOPanel_3                matlab.ui.container.Panel
        PorcentajeErrorTrapecioMultiple  matlab.ui.control.Label
        ErroresLabel_2                  matlab.ui.control.Label
        ResultadoTrapecioMultiple_Real  matlab.ui.control.Label
        ValorrealintegralLabel_3        matlab.ui.control.Label
        ResultadoTrapecioMultiple       matlab.ui.control.Label
        ElresultadodelaintegralLabel_3  matlab.ui.control.Label
        METODODETRAPECIOMULTIPLEPanel   matlab.ui.container.Panel
        CalcularButton_8                matlab.ui.control.Button
        LmitesuperiordelaintegralEditField_8  matlab.ui.control.NumericEditField
        LmitesuperiordelaintegralEditField_8Label  matlab.ui.control.Label
        LmiteinferiordelaintegralEditField_8  matlab.ui.control.NumericEditField
        LmiteinferiordelaintegralEditField_8Label  matlab.ui.control.Label
        NumerodesubintervalosEditField_8  matlab.ui.control.NumericEditField
        NmerodesubintervalosLabel_3     matlab.ui.control.Label
        FuncionEditField_8              matlab.ui.control.EditField
        FuncionEditField_8Label         matlab.ui.control.Label
        GraficaTrapecioMultiple         matlab.ui.control.UIAxes
        InterpolacionlagrangeTab        matlab.ui.container.Tab
        RESULTADOPanel_4                matlab.ui.container.Panel
        ValorAproximadoLagrange         matlab.ui.control.Label
        ElvaloraproximadoalvalordadoesLabel  matlab.ui.control.Label
        PolinomioLagrange               matlab.ui.control.Label
        ElpolinomioesLabel              matlab.ui.control.Label
        METODODEINTERPOLACIONLAGRANGEPanel  matlab.ui.container.Panel
        ListadecoordenadasenYEditField  matlab.ui.control.EditField
        ListadecoordenadasenYEditFieldLabel  matlab.ui.control.Label
        CalcularButton_9                matlab.ui.control.Button
        PuntoquedeseaaproximarEditField  matlab.ui.control.NumericEditField
        PuntoquedeseaaproximarEditFieldLabel  matlab.ui.control.Label
        ListadecoordenadasenXEditField  matlab.ui.control.EditField
        ListadecoordenadasenXEditFieldLabel  matlab.ui.control.Label
        RungeKuttaTab                   matlab.ui.container.Tab
        METODODERUNGEKUTTA3Panel        matlab.ui.container.Panel
        ValorallegarxEditField_10       matlab.ui.control.NumericEditField
        ValorallegarxEditFieldLabel     matlab.ui.control.Label
        ValorincrementohEditField_10    matlab.ui.control.NumericEditField
        ValorincrementohEditFieldLabel  matlab.ui.control.Label
        FuncionxyEditField_10           matlab.ui.control.EditField
        FuncionxyEditFieldLabel         matlab.ui.control.Label
        ValorYoEditField_10             matlab.ui.control.NumericEditField
        ValorYoEditFieldLabel           matlab.ui.control.Label
        ValorXoEditField_10             matlab.ui.control.NumericEditField
        ValorXoEditFieldLabel           matlab.ui.control.Label
        UITable_10                      matlab.ui.control.Table
        CalcularButton_10               matlab.ui.control.Button
        GraficaRungeKutta               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularBiseccion(app, event)
            %Biseccion_2(f, xl, xu, Niter, Tol)
            f = str2func("@(x)"+app.FuncionEditField.Value);
            xl = app.ValorinicialXiEditField.Value;
            xu = app.ValorfinalXsEditField.Value;
            Niter = '';
            Tol = app.CriteriodeparadaEditField.Value;
       
            %Si se ingresan menos de tres datos de entrada...        
                if  isempty(Niter) == 1 
                    metodo = 1;
                    Niter = 1000;
                    disp(newline);
                    disp('Solución por error relativo mínimo para converger');
                elseif isempty(Tol) == 1 
                    metodo = 2;
                    disp(newline);
                    disp('Solución por número máximo de iteraciones para converger');
                elseif isempty(Niter) == 0 && isempty(Tol) == 0
                    error('Niter y Tol no pueden tener un dato de entrada al mismo tiempo, uno de los dos debe estar vacío (ejemplo: Niter = [])');
                end
       
            fxl = f(xl); %Punto en Y para el límite inferior.
            fxu = f(xu); %Punto en Y para el límite superior.
            
            if fxl * fxu > 0 %Esta propiedad es la que hace que éste sea un método cerrado.
               error('No hay una raíz en ese intervalo!'); 
            end
            
            for i = 1:Niter - 1
                
                xr(i) = (xl(i) + xu(i)) / 2; %Calcula el punto medio actual.
                fxr(i) = f(xr(i)); %Evalua la función en el punto medio actual.
                
                if f(xr(i)) * f(xl(i)) > 0 %Si esta condición se cumple, la raíz NO está entre xl y xr
                    xl(i+1) = xr(i); %El punto medio es el nuevo límite inferior.
                    xu(i+1) = xu(i); %El límite superior se mantiene igual.
                    fxl(i+1) = f(xl(i+1));
                    fxu(i+1) = f(xu(i+1));
                elseif f(xr(i)) * f(xu(i)) > 0 %Si esta condición se cumple, la raíz NO está entre xl y xr
                    xu(i+1) = xr(i); %El punto medio es el nuevo límite superior.
                    xl(i+1) = xl(i); %El límite inferior se mantiene igual.
                    fxl(i+1) = f(xl(i+1));
                    fxu(i+1) = f(xu(i+1));
                end
                %Asegurarse de que si Niter es muy grande aparezca una alerta.
                try
                    xr(i+1) = (xu(i+1) + xl(i+1)) / 2; %Actulizamos el punto medio y su punto en Y
                catch
                    error('Intenta un número menor de iteraciones');
                end
                
                fxr(i+1) = f(xr(i+1));
                Error(i+1) = abs((xr(i+1) - xr(i)) / xr(i+1)) * 100; %Calcula el error relativo actual
                
                if metodo == 1
                    if Error(i+1) < Tol %Si el error relativo es menor a la tolerancia exigida, se acaba el ciclo.
                        break;
                    end
                end
            end
            ezplot(app.GraficaBisecion,f);
            %M1 = {'xl', 'xr', 'xu', 'f(xl)', 'f(xr)', 'f(xu)', 'Error relativo (%)'};
            M2 = num2cell([xl', xr', xu', fxl', fxr', fxu', Error']);
            %Matriz = [M1;M2];
            app.UITable.Data = M2;
            XR = xr(end);
            ER = Error(end);
            Iter = i+1;
        end

        % Button pushed function: CalcularButton_2
        function CalcularFalsaPosicion(app, event)
            %Si se ingresan menos de tres datos de entrada...
            %ReglaFalsaFcn(f, xl, xu, Niter, Tol)     
            f = str2func("@(x)"+app.FuncionEditField_2.Value);
            xl = app.ValorinicialXiEditField_2.Value;
            xu = app.ValorfinalXsEditField_2.Value;
            Niter = '';
            Tol = app.CriteriodeparadaEditField_2.Value; 

            if  isempty(Niter) == 1  
                metodo = 1;
                Niter = 1000;
                disp(newline);
                disp('Solución por error relativo mínimo para converger');
            elseif isempty(Tol) == 1
                metodo = 2;
                disp(newline);
                disp('Solución por número máximo de iteraciones para converger');
            elseif isempty(Niter) == 0 && isempty(Tol) == 0
                error('Niter y Tol no pueden tener un dato de entrada al mismo tiempo, uno de los dos debe estar vacío (ejemplo: Niter = [])');
            end

            fxl = f(xl); %Punto en Y para el límite inferior.
            fxu = f(xu); %Punto en Y para el límite superior.

            if fxl * fxu > 0 %Esta propiedad es la que hace que éste sea un método cerrado.
                error('No hay una raíz en ese intervalo!'); 
            end

            for i = 1:Niter - 1
                xr(i) = xu(i) - fxu(i) * ((xu(i) - xl(i)) / (fxu(i) - fxl(i))); %Calcula el punto medio falso actual.
                fxr(i) = f(xr(i)); %Evalua la función en el punto medio falso actual.
                
                if f(xr(i)) * f(xl(i)) > 0 %Si esta condición se cumple, la raíz NO está entre xl y xr
                    xl(i+1) = xr(i); %El punto medio es el nuevo límite inferior.
                    xu(i+1) = xu(i); %El límite superior se mantiene igual.
                    fxl(i+1) = f(xl(i+1));
                    fxu(i+1) = f(xu(i+1));
                elseif f(xr(i)) * f(xu(i)) > 0 %Si esta condición se cumple, la raíz NO está entre xr y xu
                    xu(i+1) = xr(i); %El punto medio es el nuevo límite superior.
                    xl(i+1) = xl(i); %El límite inferior se mantiene igual.
                    fxl(i+1) = f(xl(i+1));
                    fxu(i+1) = f(xu(i+1));
                end
                %Asegurarse de que si Niter es muy grande aparezca una alerta.
                try
                    xr(i+1)=xu(i+1)-fxu(i+1)*((xu(i+1)-xl(i+1))/(fxu(i+1)-fxl(i+1))); %Actulizamos el punto medio falso y su punto en Y
                catch
                    error('Intenta un número menor de iteraciones');
                end
                
                fxr(i+1)=f(xr(i+1));
                Error(i+1)=abs((xr(i+1)-xr(i))/xr(i+1))*100; %Calcula el error relativo actual
                
                if metodo == 1
                    if Error(i+1) < Tol %Si el error relativo es menor a la tolerancia exigida, se acaba el ciclo.
                        break;
                    end
                end
            end
            ezplot(app.GraficaFalsaPosicion,f);
            %M1 = {'xl', 'xr', 'xu', 'f(xl)', 'f(xr)', 'f(xu)', 'Error relativo (%)'};
            M2 = num2cell([xl', xr', xu', fxl', fxr', fxu', Error']);
            %M = [M1; M2];
            app.UITable2.Data = M2;
            XR = xr(end);
            ER = Error(end);
            Iter = i+1;
        end

        % Button pushed function: CalcularButton_3
        function CalcularPuntoFijo(app, event)
            f = str2func("@(x)"+app.FuncionfxEditField.Value);
            g = str2func("@(x)"+app.FunciongxEditField.Value);
            tol = app.CriteriodeparadaEditField_3.Value;
            syms x
            dg = diff(g, x);
            x = app.ValorinicialEditField_3.Value;       
            
            if(abs(eval(dg)) < 1)
                n = 0; 
                error = 100;
                while(error > tol)
                    n = n+1;
                    anterior = x;
                    x = g(x);
                    error = abs((x-anterior)/x)*100;
                    A(n,:)=[n x  error];
                    %disp([n, x, error])
                end
            else
                disp('Ingrese otra función g(x), pues con la actual, el método diverge.')
            end
            ezplot(app.GraficaPuntoFijo,f);
            app.UITable3.Data = A;
        end

        % Button pushed function: CalcularButton_4
        function CalcularNewRapshon(app, event)
        %NewtonRhapsonFcn(f,xi,Niter,es)
         f = app.FuncionEditField_4.Value;
         xi = app.ValorinicialxEditField_4.Value;
         Niter = '';
         es = app.CriteriodeparadaEditField_4.Value; 

         if  isempty(Niter) == 1 
             metodo = 1; Niter = 1000; disp(newline);
             disp('Solución por error aproximado máximo para converger');
         elseif isempty(es) == 1 
             metodo = 2; disp(newline);
             disp('Solución por número máximo de iteraciones para converger');
         elseif isempty(Niter) == 0 && isempty(es) == 0
             error('"Niter" y "es" no pueden tener un dato de entrada al mismo tiempo, uno de los dos debe estar vacío (ejemplo: Niter = [])');
         end

            fs = str2sym(f);
            vs = symvar(fs);
            dfs = diff(fs,vs,1);
            
            if subs(dfs,vs,xi) == 0
                error('Derivada de la función igual a cero en este punto de x. Utilice otro valor inicial para x');
            end
            
            osc = 0;
            for i = 1:Niter + 1
                xi_2(i) = double(xi(i) - subs(fs,vs,xi(i))/subs(dfs,vs,xi(i)));
                xi(i+1) = xi_2(i); fxi(i) = double(subs(fs,vs,xi(i)));
                ea(i) = abs((xi_2(i) - xi(i)) / xi_2(i)) * 100; %Calcula el error relativo actual
                
                if i >= 2
                    if xi(i+1) == xi(i-1) && ea(i) > es
                        osc = osc + 1;
                        if osc == 3
                            error('Divergencia oscilatoria detectada. Use otro valor inicial de x');
                        end
                    end
                end
                
                if i >= 30 && ea(i) > es
                    error('Convergencia lenta o divergencia detectada. Use otro valor inicial de x');
                end
                
                if metodo == 1
                    if ea(i) < es %Si el error relativo es menor a la tolerancia exigida, se acaba el ciclo.
                        break;
                    end
                end
            end
            ezplot(app.GraficaNewRapshon,f);
            %M1 = {'xi', 'f(xi)','Error relativo (%)'};
            M2 = num2cell([xi(1:end-1)',fxi',ea']);
            %M = [M1; M2]; 
            app.UITable4.Data = M2;
            XR = xi(end); 
            EA = ea(end);
        end

        % Button pushed function: CalcularButton_5
        function CalcularDerivada(app, event)
            syms x;
            funcion=app.FuncionEditField_5.Value;
            x0=app.XiEditField.Value;
            h=app.HEditField.Value;
            func = inline(funcion);

            % Calculos
            f0=func(x0);      
            f1=func(x0+h);   
            f2=func(x0+2*h);
            f3=func(x0+3*h);
            f4=func(x0+4*h);
            f_1=func(x0-h);
            f_2=func(x0-2*h);
            f_3=func(x0-3*h);
            f_4=func(x0-4*h);

            % 1 DERIVADA
            Derivada_01_atras=double(((3*f0)-(4*f_1)+(f_2))/(2*h));
            Derivada_01_centrada=double((-(f2)+(8*f1)-(8*f_1)+(f_2))/(12*h));
            Derivada_01_adelante=double((-(f2)+(4*f1)-(3*f0))/(2*h));

            % 2 DERIVADA
            Derivada_02_atras=double(((2*f0)-(5*f_1)+(4*f_2)-(f_3))/(h^2));
            Derivada_02_centrada=double((-(f2)+(16*f1)-(30*f0)+(16*f_1)-(f_2))/(12*h^2));
            Derivada_02_adelante=double((-(f3)+(4*f2)-(5*f1)+(2*f0))/(h^2));

            % 3 DERIVADA
            Derivada_03_atras=(((5*f0)-(18*f_1)+(24*f_2)-(14*f_3)+(3*f_4))/(2*h^3));
            Derivada_03_centrada=((-(f3)+(8*f2)-(13*f1)+(13*f_1)-(8*f_2)+(f_3))/(8*h^3));
            Derivada_03_adelante=double((-(3*f4)+(14*f3)-(24*f2)+(18*f1)-(5*f0))/(2*h^3));

            %Captura la funcion
            funcionEvaluar=str2func("@(x)"+ funcion);

            %Derivadas con valor real evaluado
            Derivada1=diff(funcionEvaluar,x);
            valorReal1=double(subs(Derivada1,x0));
            
            Derivada2=diff(Derivada1,x);
            valorReal2=double(subs(Derivada2,x0));
            
            Derivada3=diff(Derivada2,x);
            valorReal3=double(subs(Derivada3,x0));

            %%Errores Derivadas
            error_atras1= abs(((valorReal1-Derivada_01_atras)/valorReal1)*100);
            error_centrada1= abs(((valorReal1-Derivada_01_centrada)/valorReal1)*100);
            error_adelante1= abs(((valorReal1-Derivada_01_adelante)/valorReal1)*100);

            error_atras2= abs(((valorReal1-Derivada_02_atras)/valorReal2)*100);
            error_centrada2= abs(((valorReal1-Derivada_02_centrada)/valorReal2)*100);
            error_adelante2= abs(((valorReal1-Derivada_02_adelante)/valorReal2)*100);

            error_atras3= abs(((valorReal1-Derivada_03_atras)/valorReal3)*100);
            error_centrada3= abs(((valorReal1-Derivada_03_centrada)/valorReal3)*100);
            error_adelante3= abs(((valorReal1-Derivada_03_adelante)/valorReal3)*100);

            app.HaciaAtrasPrimera.Text = "Atras = " + Derivada_01_atras + " - " + " %Error = " + error_atras1;
            app.HaciaCentradaPrimera.Text = "Centrada = " + Derivada_01_centrada + " - "  + " %Error = " + error_centrada1;
            app.HaciaAdelantePrimera.Text = "Adelante = " + Derivada_01_adelante + " - "  + " %Error = " + error_adelante1;

            app.HaciaAtrasSegunda.Text = "Atras = " + Derivada_02_atras + " - "  + " %Error = " + error_atras2;
            app.HaciaCentradaSegunda.Text = "Centrada = " + Derivada_02_centrada + " - "  + " %Error = " + error_centrada2;
            app.HaciaAdelanteSegunda.Text = "Adelante = " + Derivada_02_adelante + " - "  + " %Error = " + error_adelante2;

            app.HaciaAtrasTercera.Text = "Atras = " + Derivada_03_atras + " - "  + " %Error = " + error_atras3;
            app.HaciaCentradaTercera.Text = "Centrada = " + Derivada_03_centrada + " - "  + " %Error = " + error_centrada3;
            app.HaciaAdelanteTercera.Text = "Adelante = " + Derivada_03_adelante + " - "  + " %Error = " + error_adelante3;


        end

        % Button pushed function: CalcularButton_6
        function CalcularSimpson1_3(app, event)
            %regla de simpson 1/3
            fun = app.FuncionEditField_6.Value;
            f=inline(fun);
            n=1;
            while mod(n,2)~=0
                n= app.NumerodesubintervalosEditField_6.Value;
                if mod(n,2)~=0
                    disp('El número de subintervalos debe ser par, pulse una tecla para continuar')
                    pause
                end
            end
            a= app.LmiteinferiordelaintegralEditField_6.Value;
            b= app.LmitesuperiordelaintegralEditField_6.Value;
            h=(b-a)/n;
            sumai=0;
            sumap=0;
            for i=1:2:n-1
                sumai=sumai+feval(f,h*i+a);
            end
            for i=2:2:n-2
                sumap=sumap+feval(f,h*i+a);
            end
            ezplot(app.GraficaSimpson1_3,f);
            resultado=(h/3)*(feval(f,a)+4*sumai+2*sumap+feval(f,b));
            %disp(['El resultado de la integral es ' num2str(int)])
            funcion=str2func("@(x)"+ fun);
            valorReal=integral(funcion,a,b);
            error= abs(((valorReal-resultado)/valorReal)*100);

            app.ResultadoSimpson1_3.Text = num2str(resultado) + " ";
            app.ResultadoSimpson1_3_Real.Text = num2str(valorReal) + " ";
            app.PorcentajeErrorSimpson1_3.Text = num2str(error) + " ";
        end

        % Button pushed function: CalcularButton_7
        function CalcularSimpson3_8(app, event)
            fun= app.FuncionEditField_7.Value;
            a= app.LmiteinferiordelaintegralEditField_7.Value;
            b= app.LmitesuperiordelaintegralEditField_7.Value;
            n= app.NumerodesubintervalosEditField_7.Value;     
            f= inline(fun);
            
            h=(b-a)/n;
            
            sum1=0.0;
            sum2=0.0;
            sum3=0.0;
            
            for i=1:3:n-2
                x=a+i*h;
                sum1=sum1+f(x);
            end
            for i=2:3:n-1
                x=a+i*h;
                sum2=sum2+f(x);
            end
            for i=3:3:n-3
                x=a+i*h;
                sum3=sum3+f(x);
            end
            ezplot(app.GraficaSimpson3_8,f);

            resultado=3*h*(f(a)+3.0*sum1+3.0*sum2+2.0*sum3+f(b))/8.0;

            funcion=str2func("@(x)"+ fun);
            valorReal=integral(funcion,a,b);
            error= abs(((valorReal-resultado)/valorReal)*100);

            %fprintf('Integrated value is %f',resultado)
            app.ResultadoSimpson3_8.Text = num2str(resultado) + " ";
            app.ResultadoSimpson3_8_Real.Text = num2str(valorReal) + " ";
            app.PorcentajeErrorSimpson3_8.Text = num2str(error) + " ";
        end

        % Button pushed function: CalcularButton_8
        function CalcularTrapecioMultiple(app, event)
            fun= app.FuncionEditField_8.Value;
            a= app.LmiteinferiordelaintegralEditField_8.Value;
            b= app.LmitesuperiordelaintegralEditField_8.Value;
            n= app.NumerodesubintervalosEditField_8.Value;
            
            f=inline(fun);
            
            h=(b-a)/n;
            resultado=0;
            
            for i=1:n
                resultado=h/2*(f(a+(i-1)*h)+f(a+(i)*h))+resultado;
            end
            ezplot(app.GraficaTrapecioMultiple,f);
            %fprintf("Resultado= %10.15f", s)

            funcion=str2func("@(x)"+ fun);
            valorReal=integral(funcion,a,b);
            error= abs(((valorReal-resultado)/valorReal)*100);

            app.ResultadoTrapecioMultiple.Text = num2str(resultado) + " ";
            app.ResultadoTrapecioMultiple_Real.Text = num2str(valorReal) + " ";
            app.PorcentajeErrorTrapecioMultiple.Text = num2str(error) + " ";
        end

        % Button pushed function: CalcularButton_9
        function CalcularInterpolacionLagrange(app, event)

            X = app.ListadecoordenadasenXEditField.Value;
            Y = app.ListadecoordenadasenYEditField.Value;
            P_o = app.PuntoquedeseaaproximarEditField.Value;

            n = length(X);
            L = zeros(n,n);
            
            for i=1:n % for rows
                V = 1;
                for j=1:n % for making polynomial
                    if i~=j
                        V = conv(V,poly(X(j)))/(X(i)-X(j));
                    end
                end
                L(i,:) = V*Y(i);
            end
            L;
            P = sum(L);
            F = flip(P);
            %disp('El polinomio es: ') num2str; 
            texto='';
            for k=n:-1:2
                texto = texto + " " + sprintf(' +(%.2f x^ %d) ', F(k), k-1);
            end

            app.PolinomioLagrange.Text = texto + " ";  

            A = polyval(P,P_o);
            %fprintf('/n Approximate value at given  data point is: %4.f', A);

            app.ValorAproximadoLagrange.Text = sprintf('%4.f', (A)) + " ";


        end

        % Button pushed function: CalcularButton_10
        function CalcularRungeKutta(app, event)
            
            m= app.FuncionxyEditField_10.Value;
            
            f=inline(m,'x','y');
            
            x=app.ValorXoEditField_10.Value;
            y=app.ValorYoEditField_10.Value;
            h=app.ValorincrementohEditField_10.Value;
            val=app.ValorallegarxEditField_10.Value;
            
            itr=(val-x)/h;
            n=0;
            
            for i=1:itr+1
                l=x;
                p=y;
                x=x+(0.50*h);
                k1=f(l,p);
                y=p+(0.50*k1*h);
                k2=f(x,y);
                y=p-(k1*h)+(2*k2*h);
                x=l+h;
                k3=f(x,y);
                fr=(k1/6)+(4*k2/6)+(k3/6);
                y=p+(h*fr);
                %fprintf('%2.0f %13.4f %13.4f %13.4f %17.4f\n',n,l,p,y,fr);
                M(i,:)=[n  l  p  y  fr];
                n=n+1;
            end
            ezplot(app.GraficaRungeKutta,f);
            app.UITable_10.Data= M;
            %disp(M);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BiseccionTab = uitab(app.TabGroup);
            app.BiseccionTab.Title = 'Biseccion';
            app.GraficaBisecion = uiaxes(app.BiseccionTab);
            title(app.GraficaBisecion, 'Grafico')
            xlabel(app.GraficaBisecion, 'X')
            ylabel(app.GraficaBisecion, 'Y')
            zlabel(app.GraficaBisecion, 'Z')
            app.METODODEBISECCIONPanel = uipanel(app.BiseccionTab);
            app.METODODEBISECCIONPanel.Title = 'METODO DE BISECCION';
            app.UITable = uitable(app.METODODEBISECCIONPanel);
            app.UITable.ColumnName = {'xl'; 'xr'; 'xu'; 'f(xl)'; 'f(xr)'; 'f(xu)'; 'Error relativo (%)'};
            app.UITable.RowName = {};
            app.CalcularButton = uibutton(app.METODODEBISECCIONPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularBiseccion, true);
            app.CalcularButton.Text = 'Calcular';
            app.FuncionEditFieldLabel = uilabel(app.BiseccionTab);
            app.FuncionEditFieldLabel.Text = 'Funcion:';
            app.FuncionEditField = uieditfield(app.BiseccionTab, 'text');
            app.ValorinicialXiEditFieldLabel = uilabel(app.BiseccionTab);
            app.ValorinicialXiEditFieldLabel.Text = 'Valor inicial (Xi):';
            app.ValorinicialXiEditField = uieditfield(app.BiseccionTab, 'numeric');
            app.ValorfinalXsEditFieldLabel = uilabel(app.BiseccionTab);
            app.ValorfinalXsEditFieldLabel.Text = 'Valor final (Xs):';
            app.ValorfinalXsEditField = uieditfield(app.BiseccionTab, 'numeric');
            app.CriteriodeparadaEditFieldLabel = uilabel(app.BiseccionTab);
            app.CriteriodeparadaEditFieldLabel.Text = 'Criterio de parada:';
            app.CriteriodeparadaEditField = uieditfield(app.BiseccionTab, 'numeric');
            app.FalsaposicionTab = uitab(app.TabGroup);
            app.FalsaposicionTab.Title = 'Falsa posicion';
            app.GraficaFalsaPosicion = uiaxes(app.FalsaposicionTab);
            title(app.GraficaFalsaPosicion, 'Grafico')
            xlabel(app.GraficaFalsaPosicion, 'X')
            ylabel(app.GraficaFalsaPosicion, 'Y')
            zlabel(app.GraficaFalsaPosicion, 'Z')
            app.METODODEFALSAPOSICIONPanel = uipanel(app.FalsaposicionTab);
            app.METODODEFALSAPOSICIONPanel.Title = 'METODO DE FALSA POSICION';
            app.UITable2 = uitable(app.METODODEFALSAPOSICIONPanel);
            app.UITable2.ColumnName = {'xl'; 'xr'; 'xu'; 'f(xl)'; 'f(xr)'; 'f(xu)'; 'Error relativo (%)'};
            app.UITable2.RowName = {};
            app.CalcularButton_2 = uibutton(app.METODODEFALSAPOSICIONPanel, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularFalsaPosicion, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.ValorinicialXiEditField_2Label = uilabel(app.FalsaposicionTab);
            app.ValorinicialXiEditField_2Label.Text = 'Valor inicial (Xi):';
            app.ValorinicialXiEditField_2 = uieditfield(app.FalsaposicionTab, 'numeric');
            app.FuncionEditField_2Label = uilabel(app.FalsaposicionTab);
            app.FuncionEditField_2Label.Text = 'Funcion:';
            app.FuncionEditField_2 = uieditfield(app.FalsaposicionTab, 'text');
            app.ValorfinalXsEditField_2Label = uilabel(app.FalsaposicionTab);
            app.ValorfinalXsEditField_2Label.Text = 'Valor final (Xs):';
            app.ValorfinalXsEditField_2 = uieditfield(app.FalsaposicionTab, 'numeric');
            app.CriteriodeparadaEditField_2Label = uilabel(app.FalsaposicionTab);
            app.CriteriodeparadaEditField_2Label.Text = 'Criterio de parada';
            app.CriteriodeparadaEditField_2 = uieditfield(app.FalsaposicionTab, 'numeric');
            app.PuntofijoTab = uitab(app.TabGroup);
            app.PuntofijoTab.Title = 'Punto fijo';
            app.GraficaPuntoFijo = uiaxes(app.PuntofijoTab);
            title(app.GraficaPuntoFijo, 'Grafica')
            xlabel(app.GraficaPuntoFijo, 'X')
            ylabel(app.GraficaPuntoFijo, 'Y')
            zlabel(app.GraficaPuntoFijo, 'Z')
            app.METODODEPUNTOFIJOPanel = uipanel(app.PuntofijoTab);
            app.METODODEPUNTOFIJOPanel.Title = 'METODO DE PUNTO FIJO';
            app.FuncionfxEditFieldLabel = uilabel(app.METODODEPUNTOFIJOPanel);
            app.FuncionfxEditFieldLabel.Text = 'Funcion f(x):';
            app.FuncionfxEditField = uieditfield(app.METODODEPUNTOFIJOPanel, 'text');
            app.FunciongxEditFieldLabel = uilabel(app.METODODEPUNTOFIJOPanel);
            app.FunciongxEditFieldLabel.Text = 'Funcion g(x)';
            app.FunciongxEditField = uieditfield(app.METODODEPUNTOFIJOPanel, 'text');
            app.UITable3 = uitable(app.METODODEPUNTOFIJOPanel);
            app.UITable3.ColumnName = {'i'; 'Xf(i)'; 'Error relativo(i)'};
            app.UITable3.RowName = {};
            app.CalcularButton_3 = uibutton(app.METODODEPUNTOFIJOPanel, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularPuntoFijo, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.ValorinicialEditFieldLabel = uilabel(app.PuntofijoTab);
            app.ValorinicialEditFieldLabel.Text = 'Valor inicial:';
            app.ValorinicialEditField_3 = uieditfield(app.PuntofijoTab, 'numeric');
            app.CriteriodeparadaEditField_3Label = uilabel(app.PuntofijoTab);
            app.CriteriodeparadaEditField_3Label.Text = 'Criterio de parada:';
            app.CriteriodeparadaEditField_3 = uieditfield(app.PuntofijoTab, 'numeric');
            app.NewRapshonTab = uitab(app.TabGroup);
            app.NewRapshonTab.Title = 'New Rapshon';
            app.GraficaNewRapshon = uiaxes(app.NewRapshonTab);
            title(app.GraficaNewRapshon, 'Grafica')
            xlabel(app.GraficaNewRapshon, 'X')
            ylabel(app.GraficaNewRapshon, 'Y')
            zlabel(app.GraficaNewRapshon, 'Z')
            app.METODODENEWRAPSHONPanel = uipanel(app.NewRapshonTab);
            app.METODODENEWRAPSHONPanel.Title = 'METODO DE NEW RAPSHON';
            app.CalcularButton_4 = uibutton(app.METODODENEWRAPSHONPanel, 'push');
            app.CalcularButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalcularNewRapshon, true);
            app.CalcularButton_4.Text = 'Calcular';
            app.UITable4 = uitable(app.METODODENEWRAPSHONPanel);
            app.UITable4.ColumnName = {'xi'; 'f(xi)'; 'Error relativo (%)'};
            app.UITable4.RowName = {};
            app.ValorinicialxEditFieldLabel = uilabel(app.METODODENEWRAPSHONPanel);
            app.ValorinicialxEditFieldLabel.Text = 'Valor inicial x:';
            app.ValorinicialxEditField_4 = uieditfield(app.METODODENEWRAPSHONPanel, 'numeric');
            app.CriteriodeparadaEditField_4Label = uilabel(app.METODODENEWRAPSHONPanel);
            app.CriteriodeparadaEditField_4Label.Text = 'Criterio de parada:';
            app.CriteriodeparadaEditField_4 = uieditfield(app.METODODENEWRAPSHONPanel, 'numeric');
            app.FuncionEditField_3Label = uilabel(app.METODODENEWRAPSHONPanel);
            app.FuncionEditField_3Label.Text = 'Funcion:';
            app.FuncionEditField_4 = uieditfield(app.METODODENEWRAPSHONPanel, 'text');
            app.DerivadaTab = uitab(app.TabGroup);
            app.DerivadaTab.Title = 'Derivada';
            app.METODODEDERIVADASDEALTAPRECISIONPanel = uipanel(app.DerivadaTab);
            app.METODODEDERIVADASDEALTAPRECISIONPanel.Title = 'METODO DE DERIVADAS DE ALTA PRECISION';
            app.FuncionEditField_5Label = uilabel(app.METODODEDERIVADASDEALTAPRECISIONPanel);
            app.FuncionEditField_5Label.Text = 'Funcion:';
            app.FuncionEditField_5 = uieditfield(app.METODODEDERIVADASDEALTAPRECISIONPanel, 'text');
            app.XiEditFieldLabel = uilabel(app.METODODEDERIVADASDEALTAPRECISIONPanel);
            app.XiEditFieldLabel.Text = 'Xi:';
            app.XiEditField = uieditfield(app.METODODEDERIVADASDEALTAPRECISIONPanel, 'numeric');
            app.HEditFieldLabel = uilabel(app.METODODEDERIVADASDEALTAPRECISIONPanel);
            app.HEditFieldLabel.Text = 'H:';
            app.HEditField = uieditfield(app.METODODEDERIVADASDEALTAPRECISIONPanel, 'numeric');
            app.CalcularButton_5 = uibutton(app.METODODEDERIVADASDEALTAPRECISIONPanel, 'push');
            app.CalcularButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalcularDerivada, true);
            app.CalcularButton_5.Text = 'Calcular';
            app.RESULTADOSPanel = uipanel(app.DerivadaTab);
            app.RESULTADOSPanel.Title = 'RESULTADOS';
            app.PrimerderivadaLabel = uilabel(app.RESULTADOSPanel);
            app.PrimerderivadaLabel.Text = 'Primer derivada:';
            app.HaciaAtrasPrimera = uilabel(app.RESULTADOSPanel);
            app.HaciaAtrasPrimera.Text = '';
            app.HaciaCentradaPrimera = uilabel(app.RESULTADOSPanel);
            app.HaciaCentradaPrimera.Text = '';
            app.HaciaAdelantePrimera = uilabel(app.RESULTADOSPanel);
            app.HaciaAdelantePrimera.Text = '';
            app.SegundaderivadaLabel = uilabel(app.RESULTADOSPanel);
            app.SegundaderivadaLabel.Text = 'Segunda derivada:';
            app.HaciaAtrasSegunda = uilabel(app.RESULTADOSPanel);
            app.HaciaAtrasSegunda.Text = '';
            app.HaciaCentradaSegunda = uilabel(app.RESULTADOSPanel);
            app.HaciaCentradaSegunda.Text = '';
            app.HaciaAdelanteSegunda = uilabel(app.RESULTADOSPanel);
            app.HaciaAdelanteSegunda.Text = '';
            app.TerceraderivadaLabel = uilabel(app.RESULTADOSPanel);
            app.TerceraderivadaLabel.Text = 'Tercera derivada:';
            app.HaciaAtrasTercera = uilabel(app.RESULTADOSPanel);
            app.HaciaAtrasTercera.Text = '';
            app.HaciaCentradaTercera = uilabel(app.RESULTADOSPanel);
            app.HaciaCentradaTercera.Text = '';
            app.HaciaAdelanteTercera = uilabel(app.RESULTADOSPanel);
            app.HaciaAdelanteTercera.Text = '';
            app.Simpson13Tab = uitab(app.TabGroup);
            app.Simpson13Tab.Title = 'Simpson 1/3';
            app.GraficaSimpson1_3 = uiaxes(app.Simpson13Tab);
            title(app.GraficaSimpson1_3, 'Title')
            xlabel(app.GraficaSimpson1_3, 'X')
            ylabel(app.GraficaSimpson1_3, 'Y')
            zlabel(app.GraficaSimpson1_3, 'Z')
            app.METODODESIMPSON13Panel = uipanel(app.Simpson13Tab);
            app.METODODESIMPSON13Panel.Title = 'METODO DE SIMPSON 1/3';
            app.FuncionEditField_6Label = uilabel(app.METODODESIMPSON13Panel);
            app.FuncionEditField_6Label.Text = 'Funcion:';
            app.FuncionEditField_6 = uieditfield(app.METODODESIMPSON13Panel, 'text');
            app.NmerodesubintervalosLabel = uilabel(app.METODODESIMPSON13Panel);
            app.NmerodesubintervalosLabel.Text = 'Número de subintervalos:';
            app.NumerodesubintervalosEditField_6 = uieditfield(app.METODODESIMPSON13Panel, 'numeric');
            app.LmiteinferiordelaintegralLabel = uilabel(app.METODODESIMPSON13Panel);
            app.LmiteinferiordelaintegralLabel.Text = 'Límite inferior de la integral:';
            app.LmiteinferiordelaintegralEditField_6 = uieditfield(app.METODODESIMPSON13Panel, 'numeric');
            app.LmitesuperiordelaintegralEditFieldLabel = uilabel(app.METODODESIMPSON13Panel);
            app.LmitesuperiordelaintegralEditFieldLabel.Text = 'Límite superior de la integral';
            app.LmitesuperiordelaintegralEditField_6 = uieditfield(app.METODODESIMPSON13Panel, 'numeric');
            app.CalcularButton_6 = uibutton(app.METODODESIMPSON13Panel, 'push');
            app.CalcularButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalcularSimpson1_3, true);
            app.CalcularButton_6.Text = 'Calcular';
            app.RESULTADOPanel = uipanel(app.Simpson13Tab);
            app.RESULTADOPanel.Title = 'RESULTADO';
            app.ElresultadodelaintegralLabel = uilabel(app.RESULTADOPanel);
            app.ElresultadodelaintegralLabel.Text = 'El resultado de la integral:';
            app.ResultadoSimpson1_3 = uilabel(app.RESULTADOPanel);
            app.ResultadoSimpson1_3.Text = '.';
            app.ValorrealintegralLabel = uilabel(app.RESULTADOPanel);
            app.ValorrealintegralLabel.Text = 'Valor real integral:';
            app.ResultadoSimpson1_3_Real = uilabel(app.RESULTADOPanel);
            app.ResultadoSimpson1_3_Real.Text = '.';
            app.ErrorLabel = uilabel(app.RESULTADOPanel);
            app.ErrorLabel.Text = '% Error es:';
            app.PorcentajeErrorSimpson1_3 = uilabel(app.RESULTADOPanel);
            app.PorcentajeErrorSimpson1_3.Text = '.';
            app.Simpson38Tab = uitab(app.TabGroup);
            app.Simpson38Tab.Title = 'Simpson 3/8';
            app.GraficaSimpson3_8 = uiaxes(app.Simpson38Tab);
            title(app.GraficaSimpson3_8, 'Title')
            xlabel(app.GraficaSimpson3_8, 'X')
            ylabel(app.GraficaSimpson3_8, 'Y')
            zlabel(app.GraficaSimpson3_8, 'Z')
            app.METODODESIMPSON38Panel = uipanel(app.Simpson38Tab);
            app.METODODESIMPSON38Panel.Title = 'METODO DE SIMPSON 3/8';
            app.FuncionEditField_7Label = uilabel(app.METODODESIMPSON38Panel);
            app.FuncionEditField_7Label.Text = 'Funcion:';
            app.FuncionEditField_7 = uieditfield(app.METODODESIMPSON38Panel, 'text');
            app.NmerodesubintervalosLabel_2 = uilabel(app.METODODESIMPSON38Panel);
            app.NmerodesubintervalosLabel_2.Text = 'Número de subintervalos:';
            app.NumerodesubintervalosEditField_7 = uieditfield(app.METODODESIMPSON38Panel, 'numeric');
            app.LmiteinferiordelaintegralEditField_7Label = uilabel(app.METODODESIMPSON38Panel);
            app.LmiteinferiordelaintegralEditField_7Label.Text = 'Límite inferior de la integral:';
            app.LmiteinferiordelaintegralEditField_7 = uieditfield(app.METODODESIMPSON38Panel, 'numeric');
            app.LmitesuperiordelaintegralEditField_7Label = uilabel(app.METODODESIMPSON38Panel);
            app.LmitesuperiordelaintegralEditField_7Label.Text = 'Límite superior de la integral';
            app.LmitesuperiordelaintegralEditField_7 = uieditfield(app.METODODESIMPSON38Panel, 'numeric');
            app.CalcularButton_7 = uibutton(app.METODODESIMPSON38Panel, 'push');
            app.CalcularButton_7.ButtonPushedFcn = createCallbackFcn(app, @CalcularSimpson3_8, true);
            app.CalcularButton_7.Text = 'Calcular';
            app.RESULTADOPanel_2 = uipanel(app.Simpson38Tab);
            app.RESULTADOPanel_2.Title = 'RESULTADO';
            app.ElresultadodelaintegralLabel_2 = uilabel(app.RESULTADOPanel_2);
            app.ElresultadodelaintegralLabel_2.Text = 'El resultado de la integral:';
            app.ResultadoSimpson3_8 = uilabel(app.RESULTADOPanel_2);
            app.ResultadoSimpson3_8.Text = '.';
            app.ValorrealintegralLabel_2 = uilabel(app.RESULTADOPanel_2);
            app.ValorrealintegralLabel_2.Text = 'Valor real integral:';
            app.ResultadoSimpson3_8_Real = uilabel(app.RESULTADOPanel_2);
            app.ResultadoSimpson3_8_Real.Text = '.';
            app.ErroresLabel = uilabel(app.RESULTADOPanel_2);
            app.ErroresLabel.Text = '% Error es:';
            app.PorcentajeErrorSimpson3_8 = uilabel(app.RESULTADOPanel_2);
            app.PorcentajeErrorSimpson3_8.Text = '.';
            app.TrapeciomultipleTab = uitab(app.TabGroup);
            app.TrapeciomultipleTab.Title = 'Trapecio multiple';
            app.GraficaTrapecioMultiple = uiaxes(app.TrapeciomultipleTab);
            title(app.GraficaTrapecioMultiple, 'Title')
            xlabel(app.GraficaTrapecioMultiple, 'X')
            ylabel(app.GraficaTrapecioMultiple, 'Y')
            zlabel(app.GraficaTrapecioMultiple, 'Z')
            app.METODODETRAPECIOMULTIPLEPanel = uipanel(app.TrapeciomultipleTab);
            app.METODODETRAPECIOMULTIPLEPanel.Title = 'METODO DE TRAPECIO MULTIPLE';
            app.FuncionEditField_8Label = uilabel(app.METODODETRAPECIOMULTIPLEPanel);
            app.FuncionEditField_8Label.Text = 'Funcion:';
            app.FuncionEditField_8 = uieditfield(app.METODODETRAPECIOMULTIPLEPanel, 'text');
            app.NmerodesubintervalosLabel_3 = uilabel(app.METODODETRAPECIOMULTIPLEPanel);
            app.NmerodesubintervalosLabel_3.Text = 'Número de subintervalos:';
            app.NumerodesubintervalosEditField_8 = uieditfield(app.METODODETRAPECIOMULTIPLEPanel, 'numeric');
            app.LmiteinferiordelaintegralEditField_8Label = uilabel(app.METODODETRAPECIOMULTIPLEPanel);
            app.LmiteinferiordelaintegralEditField_8Label.Text = 'Límite inferior de la integral:';
            app.LmiteinferiordelaintegralEditField_8 = uieditfield(app.METODODETRAPECIOMULTIPLEPanel, 'numeric');
            app.LmitesuperiordelaintegralEditField_8Label = uilabel(app.METODODETRAPECIOMULTIPLEPanel);
            app.LmitesuperiordelaintegralEditField_8Label.Text = 'Límite superior de la integral';
            app.LmitesuperiordelaintegralEditField_8 = uieditfield(app.METODODETRAPECIOMULTIPLEPanel, 'numeric');
            app.CalcularButton_8 = uibutton(app.METODODETRAPECIOMULTIPLEPanel, 'push');
            app.CalcularButton_8.ButtonPushedFcn = createCallbackFcn(app, @CalcularTrapecioMultiple, true);
            app.CalcularButton_8.Text = 'Calcular';
            app.RESULTADOPanel_3 = uipanel(app.TrapeciomultipleTab);
            app.RESULTADOPanel_3.Title = 'RESULTADO';
            app.ElresultadodelaintegralLabel_3 = uilabel(app.RESULTADOPanel_3);
            app.ElresultadodelaintegralLabel_3.Text = 'El resultado de la integral:';
            app.ResultadoTrapecioMultiple = uilabel(app.RESULTADOPanel_3);
            app.ResultadoTrapecioMultiple.Text = '.';
            app.ValorrealintegralLabel_3 = uilabel(app.RESULTADOPanel_3);
            app.ValorrealintegralLabel_3.Text = 'Valor real integral:';
            app.ResultadoTrapecioMultiple_Real = uilabel(app.RESULTADOPanel_3);
            app.ResultadoTrapecioMultiple_Real.Text = '.';
            app.ErroresLabel_2 = uilabel(app.RESULTADOPanel_3);
            app.ErroresLabel_2.Text = '% Error es:';
            app.PorcentajeErrorTrapecioMultiple = uilabel(app.RESULTADOPanel_3);
            app.PorcentajeErrorTrapecioMultiple.Text = '.';
            app.InterpolacionlagrangeTab = uitab(app.TabGroup);
            app.InterpolacionlagrangeTab.Title = 'Interpolacion lagrange';
            app.METODODEINTERPOLACIONLAGRANGEPanel = uipanel(app.InterpolacionlagrangeTab);
            app.METODODEINTERPOLACIONLAGRANGEPanel.Title = 'METODO DE INTERPOLACION LAGRANGE';
            app.ListadecoordenadasenXEditFieldLabel = uilabel(app.METODODEINTERPOLACIONLAGRANGEPanel);
            app.ListadecoordenadasenXEditFieldLabel.Text = 'Lista de coordenadas en X:';
            app.ListadecoordenadasenXEditField = uieditfield(app.METODODEINTERPOLACIONLAGRANGEPanel, 'text');
            app.PuntoquedeseaaproximarEditFieldLabel = uilabel(app.METODODEINTERPOLACIONLAGRANGEPanel);
            app.PuntoquedeseaaproximarEditFieldLabel.Text = 'Punto que desea aproximar:';
            app.PuntoquedeseaaproximarEditField = uieditfield(app.METODODEINTERPOLACIONLAGRANGEPanel, 'numeric');
            app.CalcularButton_9 = uibutton(app.METODODEINTERPOLACIONLAGRANGEPanel, 'push');
            app.CalcularButton_9.ButtonPushedFcn = createCallbackFcn(app, @CalcularInterpolacionLagrange, true);
            app.CalcularButton_9.Text = 'Calcular';
            app.ListadecoordenadasenYEditFieldLabel = uilabel(app.METODODEINTERPOLACIONLAGRANGEPanel);
            app.ListadecoordenadasenYEditFieldLabel.Text = 'Lista de coordenadas en Y:';
            app.ListadecoordenadasenYEditField = uieditfield(app.METODODEINTERPOLACIONLAGRANGEPanel, 'text');
            app.RESULTADOPanel_4 = uipanel(app.InterpolacionlagrangeTab);
            app.RESULTADOPanel_4.Title = 'RESULTADO';
            app.ElpolinomioesLabel = uilabel(app.RESULTADOPanel_4);
            app.ElpolinomioesLabel.Text = 'El polinomio es:';
            app.PolinomioLagrange = uilabel(app.RESULTADOPanel_4);
            app.PolinomioLagrange.Text = '.';
            app.ElvaloraproximadoalvalordadoesLabel = uilabel(app.RESULTADOPanel_4);
            app.ElvaloraproximadoalvalordadoesLabel.Text = 'El valor aproximado al valor dado es: ';
            app.ValorAproximadoLagrange = uilabel(app.RESULTADOPanel_4);
            app.ValorAproximadoLagrange.Text = '.';
            app.RungeKuttaTab = uitab(app.TabGroup);
            app.RungeKuttaTab.Title = 'Runge-Kutta';
            app.GraficaRungeKutta = uiaxes(app.RungeKuttaTab);
            title(app.GraficaRungeKutta, 'Title')
            xlabel(app.GraficaRungeKutta, 'X')
            ylabel(app.GraficaRungeKutta, 'Y')
            zlabel(app.GraficaRungeKutta, 'Z')
            app.METODODERUNGEKUTTA3Panel = uipanel(app.RungeKuttaTab);
            app.METODODERUNGEKUTTA3Panel.Title = 'METODO DE RUNGE-KUTTA 3';
            app.CalcularButton_10 = uibutton(app.METODODERUNGEKUTTA3Panel, 'push');
            app.CalcularButton_10.ButtonPushedFcn = createCallbackFcn(app, @CalcularRungeKutta, true);
            app.CalcularButton_10.Text = 'Calcular';
            app.UITable_10 = uitable(app.METODODERUNGEKUTTA3Panel);
            app.UITable_10.ColumnName = {'itrn'; 'valor x'; 'valor y'; 'evaluado y'; 'valor pendiente'};
            app.UITable_10.RowName = {};
            app.ValorXoEditFieldLabel = uilabel(app.METODODERUNGEKUTTA3Panel);
            app.ValorXoEditFieldLabel.Text = 'Valor Xo:';
            app.ValorXoEditField_10 = uieditfield(app.METODODERUNGEKUTTA3Panel, 'numeric');
            app.ValorYoEditFieldLabel = uilabel(app.METODODERUNGEKUTTA3Panel);
            app.ValorYoEditFieldLabel.Text = 'Valor Yo';
            app.ValorYoEditField_10 = uieditfield(app.METODODERUNGEKUTTA3Panel, 'numeric');
            app.FuncionxyEditFieldLabel = uilabel(app.METODODERUNGEKUTTA3Panel);
            app.FuncionxyEditFieldLabel.Text = 'Funcion (x,y):';
            app.FuncionxyEditField_10 = uieditfield(app.METODODERUNGEKUTTA3Panel, 'text');
            app.ValorincrementohEditFieldLabel = uilabel(app.METODODERUNGEKUTTA3Panel);
            app.ValorincrementohEditFieldLabel.Text = 'Valor incremento h:';
            app.ValorincrementohEditField_10 = uieditfield(app.METODODERUNGEKUTTA3Panel, 'numeric');
            app.ValorallegarxEditFieldLabel = uilabel(app.METODODERUNGEKUTTA3Panel);
            app.ValorallegarxEditFieldLabel.Text = 'Valor a llegar x';
            app.ValorallegarxEditField_10 = uieditfield(app.METODODERUNGEKUTTA3Panel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end